classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EnterthepathofatextfileEditFieldLabel  matlab.ui.control.Label
        EnterthepathofatextfileEditField  matlab.ui.control.EditField
        SelectButton           matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        ExitButton             matlab.ui.control.Button
        EntropyEditFieldLabel  matlab.ui.control.Label
        EntropyEditField       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [filename , path] = uigetfile ('*.txt') ;
            temp = fileread (filename);
            app.EnterthepathofatextfileEditField.Value = temp ;
            
            %Entropy FN%    
            
            % Checks whether temp is a character array
            if (ischar(temp)==1)    
                l=length(temp);
                uniqueChars = unique(temp);   % String temp has all unique characters sorted
                lenChar=length(uniqueChars);
                f=zeros(1,lenChar);
                for i=1:lenChar
                    % Count the occurence of unique characters
                    f(i)=length(findstr(temp,uniqueChars(i)));   
                end
                p=zeros(1,lenChar);
                for i=1:lenChar
                    %  Probabilities for each unique character 
                    p(i)=f(i)/l;       
                end
                H=0;
                for  i=1:lenChar
                     % Calculating the Entropy
                    H = H + (-p(i)*log2(p(i))); 
                end 
            end
%  Entropy FN end       %
%print entropy
            app.EntropyEditField.Value = num2str(H);
            figure (app.UIFigure);
        end

        % Value changed function: EnterthepathofatextfileEditField
        function EnterthepathofatextfileEditFieldValueChanged(app, event)
            value = app.EnterthepathofatextfileEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            plot(app.UIAxes,pdf(temp,[0 10]));
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Value changed function: EntropyEditField
        function EntropyEditFieldValueChanged(app, event)
            value = app.EntropyEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnterthepathofatextfileEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthepathofatextfileEditFieldLabel.Text = 'Enter the path of a text file';
            app.EnterthepathofatextfileEditField = uieditfield(app.UIFigure, 'text');
            app.EnterthepathofatextfileEditField.ValueChangedFcn = createCallbackFcn(app, @EnterthepathofatextfileEditFieldValueChanged, true);
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Distribution')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.EntropyEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.EntropyEditField = uieditfield(app.UIFigure, 'text');
            app.EntropyEditField.ValueChangedFcn = createCallbackFcn(app, @EntropyEditFieldValueChanged, true);
            app.EntropyEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end